classdef Fourier_Series_and_Power_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image4                          matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        ClicktoproceedButton            matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        FourierSeriesandPowerCalculatorLabel  matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        VoltageSignalTab                matlab.ui.container.Tab
        NonSinusoidalPeriodicVoltageSignalLabel  matlab.ui.control.Label
        NumberofFourierCoefficients515recommendedEditField  matlab.ui.control.NumericEditField
        NumberofFourierCoefficientsLabel  matlab.ui.control.Label
        ChoosetheVoltageSignalTypeDropDown  matlab.ui.control.DropDown
        ChoosetheVoltageSignalTypeDropDownLabel  matlab.ui.control.Label
        FindFourierSeriesButton         matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        PropertiesoftheSignalPanel      matlab.ui.container.Panel
        VerticalShiftingEditField       matlab.ui.control.NumericEditField
        VerticalShiftingEditFieldLabel  matlab.ui.control.Label
        TimeShiftingEditField           matlab.ui.control.NumericEditField
        TimeShiftingEditFieldLabel      matlab.ui.control.Label
        AmplitudeAEditField             matlab.ui.control.NumericEditField
        AmplitudeAEditFieldLabel        matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.NumericEditField
        DutyCycleLabel                  matlab.ui.control.Label
        TimePeriodTsEditField           matlab.ui.control.NumericEditField
        TimePeriodTsEditFieldLabel      matlab.ui.control.Label
        SamplingFrequency2050HzrecommendedEditField  matlab.ui.control.NumericEditField
        SamplingFrequency2050HzrecommendedEditFieldLabel  matlab.ui.control.Label
        FunctionftEditField             matlab.ui.control.EditField
        FunctionftLabel                 matlab.ui.control.Label
        FourierSeriesTab                matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        UIAxes1                         matlab.ui.control.UIAxes
        Panel_5                         matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        CalculatePowerRelatedtermsButton  matlab.ui.control.Button
        RMSvalueEditField               matlab.ui.control.NumericEditField
        RMSvalueLabel                   matlab.ui.control.Label
        AverageValueEditField           matlab.ui.control.NumericEditField
        AverageValueLabel               matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Panel_2                         matlab.ui.container.Panel
        PnTextArea                      matlab.ui.control.TextArea
        PnTextAreaLabel                 matlab.ui.control.Label
        AnTextArea                      matlab.ui.control.TextArea
        AnTextAreaLabel                 matlab.ui.control.Label
        a0EditField_2                   matlab.ui.control.NumericEditField
        a0EditField_2Label              matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        bnTextArea                      matlab.ui.control.TextArea
        bnTextAreaLabel                 matlab.ui.control.Label
        anTextArea                      matlab.ui.control.TextArea
        anTextAreaLabel                 matlab.ui.control.Label
        a0EditField                     matlab.ui.control.NumericEditField
        a0EditFieldLabel                matlab.ui.control.Label
        FourierSeriesLabel              matlab.ui.control.Label
        PowerRelatedTermsTab            matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        TruePowerFactorPFEditField      matlab.ui.control.EditField
        TruePowerFactorPFEditFieldLabel  matlab.ui.control.Label
        DistortionpowerfactorDPFEditField  matlab.ui.control.EditField
        DistortionpowerfactorDPFEditFieldLabel  matlab.ui.control.Label
        DisplacementpowerfactorPFDEditField  matlab.ui.control.EditField
        DisplacementpowerfactorPFDEditFieldLabel  matlab.ui.control.Label
        TotalHarmonicDistortionrootmeansquareTHDREditField  matlab.ui.control.EditField
        TotalHarmonicDistortionrootmeansquareTHDREditFieldLabel  matlab.ui.control.Label
        TotalHarmonicDistortionfundamentalfrequencyTHDFEditField  matlab.ui.control.EditField
        TotalHarmonicDistortionfundamentalfrequencyTHDFEditFieldLabel  matlab.ui.control.Label
        ComplexPowerSEditField          matlab.ui.control.EditField
        ComplexPowerSEditFieldLabel     matlab.ui.control.Label
        ApparentPowerSEditField         matlab.ui.control.EditField
        ApparentPowerSEditFieldLabel    matlab.ui.control.Label
        ReactivePowerQEditField         matlab.ui.control.EditField
        ReactivePowerQEditFieldLabel    matlab.ui.control.Label
        RealAveragePowerPEditField      matlab.ui.control.EditField
        RealAveragePowerPEditFieldLabel  matlab.ui.control.Label
        PowerFactorTypeEditField        matlab.ui.control.EditField
        PowerFactorTypeEditFieldLabel   matlab.ui.control.Label
        LoadValuesPanel                 matlab.ui.container.Panel
        CapacitanceFaradEditField       matlab.ui.control.NumericEditField
        CapacitanceFaradEditFieldLabel  matlab.ui.control.Label
        InductanceHenryEditField        matlab.ui.control.NumericEditField
        InductanceHenryEditFieldLabel   matlab.ui.control.Label
        ResistanceohmEditField          matlab.ui.control.NumericEditField
        ResistanceohmEditFieldLabel     matlab.ui.control.Label
        ChooseloadtypeDropDown          matlab.ui.control.DropDown
        ChooseloadtypeDropDownLabel     matlab.ui.control.Label
        PowerRelatedTermsLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        f;
        fs;
        T;
        Dc;
        amp;
        xs;
        vs;
        N;
        W;
        a;
        a0;
        an;
        bn;
        An;
        Pn;
        F;
        Wn;
        R;
        L;
        C;
        P;
        Q;
        S;
        THDF;
        THDR;
        PFD;
        DPF;
        PF;
        % Description
    end
    
    methods (Access = private)
        
        function [] = choicesquare(app)
            [app.a0,app.an,app.bn,app.An,app.Pn,app.F,app.Wn]=f_square(app.fs,app.T,app.Dc,app.amp,app.xs,app.vs,app.N);
        end
        
        function [] = choicerect(app)
            [app.a0,app.an,app.bn,app.An,app.Pn,app.F,app.Wn] = f_rect(app.fs,app.T,app.W,app.amp,app.xs,app.vs,app.N);
        end
        
        function [] = draw_rect(app)
            t1=-(2*app.T+(app.W/2)-app.xs)-1/app.fs:1/app.fs:-(app.T+(app.W/2)-app.xs);
            x1=(app.amp*rectpuls(t1+2*app.T-app.xs,app.W))+app.vs;
            t2=-(app.T+(app.W/2)-app.xs)-1/app.fs:1/app.fs:-((app.W/2)-app.xs);
            x2=(app.amp*rectpuls(t2+app.T-app.xs,app.W))+app.vs;
            t3=-((app.W/2)-app.xs)-1/app.fs:1/app.fs:(app.T-(app.W/2)+app.xs);
            x3=(app.amp*rectpuls(t3-app.xs,app.W))+app.vs;
            t4=(app.T-(app.W/2)+app.xs)-1/app.fs:1/app.fs:(2*app.T-(app.W/2)+app.xs);
            x4=(app.amp*rectpuls(t4-app.T-app.xs,app.W))+app.vs;
            t5=(2*app.T-(app.W/2)+app.xs)-1/app.fs:1/app.fs:(2*app.T+(app.W/2)+app.xs)+1/app.fs;
            x5=(app.amp*rectpuls(t5-(2*app.T)-app.xs,app.W))+app.vs;
            k=-(2*app.T+(app.W/2)-app.xs)-1/app.fs:1/app.fs:(2*app.T+(app.W/2)+app.xs)+1/app.fs;
    
            hold(app.UIAxes1,"on");
            plot(app.UIAxes1,t1,x1,'b');
            plot(app.UIAxes1,t2,x2,'b');
            plot(app.UIAxes1,t3,x3,'b');
            plot(app.UIAxes1,t4,x4,'b');
            plot(app.UIAxes1,t5,x5,'b');
            plot(app.UIAxes1,k,app.F,'r');
            hold(app.UIAxes1,"off");     
        end
        
        function [] = choicetriangle(app)
            [app.a0,app.an,app.bn,app.An,app.Pn,app.F,app.Wn] = f_triangle(app.fs,app.T,app.amp,app.xs,app.vs,app.N);
        end
        
        function [] = choicesawtooth(app)
            [app.a0,app.an,app.bn,app.An,app.Pn,app.F,app.Wn] = f_sawtooth(app.fs,app.T,app.amp,app.xs,app.vs,app.N);
        end
        
        function [] = choicefunction(app)
            [app.a0,app.an,app.bn,app.An,app.Pn,app.F,app.Wn] = f_function(app.fs,app.f,app.a,app.T,app.xs,app.vs,app.N);
        end
        
        function [] = draw_func(app)
            p= (app.a) : 1/app.fs : (app.a+app.T);
            l=length(p);
            for i=1:l
                x(i)=app.f(p(i))+app.vs;
            end

            y1=app.f(app.a)+app.vs;
            y2=app.f(app.a+app.T)+app.vs;
            if y2>y1
                y=linspace(y1,y2,1000);
                X1=zeros(1,1000);
                X2=zeros(1,1000);
                X3=zeros(1,1000);
                X4=zeros(1,1000);
                X5=zeros(1,1000);
                X6=zeros(1,1000);
            else
                y=linspace(y2,y1,1000);
                X1=zeros(1,1000);
                X2=zeros(1,1000);
                X3=zeros(1,1000);
                X4=zeros(1,1000);
                X5=zeros(1,1000);
                X6=zeros(1,1000);
            end

            t1= (app.a-2*app.T)+app.xs : 1/app.fs : (app.a-app.T)+app.xs;
            x1=  x;
            X1(1,:)=(app.a-2*app.T)+app.xs;
            X2(1,:)=(app.a-app.T)+app.xs;
            t2= (app.a-app.T)+app.xs : 1/app.fs : (app.a)+app.xs;
            x2=  x;
            X3(1,:)=(app.a)+app.xs;
            t3= (app.a)+app.xs : 1/app.fs : (app.a+app.T)+app.xs;
            x3=  x;
            X4(1,:)=(app.a+app.T)+app.xs;
            t4= (app.a+app.T)+app.xs : 1/app.fs : (app.a+2*app.T)+app.xs;
            x4=  x;
            X5(1,:)=(app.a+2*app.T)+app.xs;
            t5= (app.a+2*app.T)+app.xs : 1/app.fs : (app.a+3*app.T)+app.xs;
            x5=  x;
            X6(1,:)=(app.a+3*app.T)+app.xs;
            k=((app.a-2*app.T)+app.xs) : 1/app.fs : ((app.a+3*app.T)+app.xs);

            hold(app.UIAxes1,"on");
            plot(app.UIAxes1,X1,y,'b');%line 1 drawn
            plot(app.UIAxes1,t1,x1,'b');%function 1 drawn
            plot(app.UIAxes1,X2,y,'b');%line 2 drawn
            plot(app.UIAxes1,t2,x2,'b');%function 2 drawn
            plot(app.UIAxes1,X3,y,'b');%line 3 drawn
            plot(app.UIAxes1,t3,x3,'b');%function 3 drawn
            plot(app.UIAxes1,X4,y,'b');%line 4 drawn
            plot(app.UIAxes1,t4,x4,'b');%function 4 drawn
            plot(app.UIAxes1,X5,y,'b');%line 5 drawn
            plot(app.UIAxes1,t5,x5,'b');%function 5 drawn
            plot(app.UIAxes1,X6,y,'b');%line 6 drawn
            plot(app.UIAxes1,k,app.F,'r');
            hold(app.UIAxes1,"off");
        end
        
        function [] = PowR(app)
            [app.P,app.Q,app.S,app.THDF,app.THDR,app.PFD,app.DPF,app.PF]=PowerR(app.a0,app.An,app.Pn,app.N,app.R);
        end
        
        function [] = PowL(app)
            [app.P,app.Q,app.S,app.THDF,app.THDR,app.PFD,app.DPF,app.PF]=PowerL(app.a0,app.An,app.Pn,app.N,app.L,app.T);
        end
        
        function [] = PowC(app)
            [app.P,app.Q,app.S,app.THDF,app.THDR,app.PFD,app.DPF,app.PF]=PowerC(app.a0,app.An,app.Pn,app.N,app.C,app.T);
        end
        
        function [] = PowRL(app)
            [app.P,app.Q,app.S,app.THDF,app.THDR,app.PFD,app.DPF,app.PF]=PowerRL(app.a0,app.An,app.Pn,app.N,app.R,app.L,app.T);
        end
        
        function [] = PowRC(app)
            [app.P,app.Q,app.S,app.THDF,app.THDR,app.PFD,app.DPF,app.PF]=PowerRC(app.a0,app.An,app.Pn,app.N,app.R,app.C,app.T);
        end
        
        function [] = PowRLC(app)
            [app.P,app.Q,app.S,app.THDF,app.THDR,app.PFD,app.DPF,app.PF]=PowerRLC(app.a0,app.An,app.Pn,app.N,app.R,app.L,app.C,app.T);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChoosetheVoltageSignalTypeDropDown
        function ChoosetheVoltageSignalTypeDropDownValueChanged(app, event)
            value = app.ChoosetheVoltageSignalTypeDropDown.Value;
            
            switch value
                case 1
                    app.FunctionftEditField.Visible='off';
                    app.FunctionftLabel.Visible='off';
                    app.AmplitudeAEditFieldLabel.Text='Amplitude, A';
                    app.DutyCycleEditField.Visible='on';
                    app.DutyCycleLabel.Visible='on';
                    app.DutyCycleLabel.Text='Duty Cycle (%)';
                case 2
                    app.FunctionftEditField.Visible='off';
                    app.FunctionftLabel.Visible='off';
                    app.AmplitudeAEditFieldLabel.Text='Amplitude, A';
                    app.DutyCycleEditField.Visible='on';
                    app.DutyCycleLabel.Visible='on';
                    app.DutyCycleLabel.Text='Width, W (< Time period)';
                case 3
                    app.FunctionftEditField.Visible='off';
                    app.FunctionftLabel.Visible='off';
                    app.AmplitudeAEditFieldLabel.Text='Amplitude, A';
                    app.DutyCycleEditField.Visible='off';
                    app.DutyCycleLabel.Visible='off';
                case 4
                    app.FunctionftEditField.Visible='off';
                    app.FunctionftLabel.Visible='off';
                    app.AmplitudeAEditFieldLabel.Text='Amplitude, A';
                    app.DutyCycleEditField.Visible='off';
                    app.DutyCycleLabel.Visible='off';
                case 5
                    app.FunctionftEditField.Visible='on';
                    app.FunctionftLabel.Visible='on';
                    app.AmplitudeAEditFieldLabel.Text='Starting point of the central period, a';
                    app.DutyCycleEditField.Visible='off';
                    app.DutyCycleLabel.Visible='off';
            end
        end

        % Value changed function: DutyCycleEditField
        function DutyCycleEditFieldValueChanged(app, event)
%             value = app.DutyCycleEditField.Value;
%             if app.ChoosetheVoltageSignalTypeDropDown.Value==1
%                 app.Dc = value;
%             elseif app.ChoosetheVoltageSignalTypeDropDown.Value==2
%                 app.W = value ;
%             end
        end

        % Button pushed function: FindFourierSeriesButton
        function FindFourierSeriesButtonPushed(app, event)
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);

            value = app.ChoosetheVoltageSignalTypeDropDown.Value;
            switch value
                case 1
                    app.fs=app.SamplingFrequency2050HzrecommendedEditField.Value;
                    app.T=app.TimePeriodTsEditField.Value;
                    app.Dc=app.DutyCycleEditField.Value;
                    app.amp=app.AmplitudeAEditField.Value;
                    app.xs=app.TimeShiftingEditField.Value;
                    app.vs=app.VerticalShiftingEditField.Value;
                    app.N=app.NumberofFourierCoefficients515recommendedEditField.Value;

                    choicesquare(app);

                    k=-((5*app.T/2)-1/app.fs):1/app.fs:((5*app.T/2)-1/app.fs);
                    w0=(2*pi)/app.T;
                    y=(app.amp*square(w0*(k-app.xs),app.Dc))+app.vs;%Dc=Duty Cycle

                    hold(app.UIAxes1,"on");
                    plot(app.UIAxes1,k,y,'b');
                    plot(app.UIAxes1,k,app.F,'r');
                    hold(app.UIAxes1,"off");
                    stem(app.UIAxes2,app.Wn,app.An);
                    stem(app.UIAxes3,app.Wn,app.Pn);

                    app.TabGroup.SelectedTab = app.FourierSeriesTab;

                case 2
                    app.fs=app.SamplingFrequency2050HzrecommendedEditField.Value;
                    app.T=app.TimePeriodTsEditField.Value;
                    app.W=app.DutyCycleEditField.Value;
                    app.amp=app.AmplitudeAEditField.Value;
                    app.xs=app.TimeShiftingEditField.Value;
                    app.vs=app.VerticalShiftingEditField.Value;
                    app.N=app.NumberofFourierCoefficients515recommendedEditField.Value;
                    if app.W>=app.T
                        msgbox('Width must be less than Time Period!','Error');
                    else
                        choicerect(app);
  
                        draw_rect(app);
                        stem(app.UIAxes2,app.Wn,app.An);
                        stem(app.UIAxes3,app.Wn,app.Pn);

                        app.TabGroup.SelectedTab = app.FourierSeriesTab;
                    end

                case 3
                    app.fs=app.SamplingFrequency2050HzrecommendedEditField.Value;
                    app.T=app.TimePeriodTsEditField.Value;
                    app.amp=app.AmplitudeAEditField.Value;
                    app.xs=app.TimeShiftingEditField.Value;
                    app.vs=app.VerticalShiftingEditField.Value;
                    app.N=app.NumberofFourierCoefficients515recommendedEditField.Value;

                    choicetriangle(app);

                    Incl=0.5;
                    w0=(2*pi)/app.T;
                    k=-((5*app.T/2)-1/app.fs):1/app.fs:((5*app.T/2)-1/app.fs);
                    y=(app.amp*sawtooth(w0*(k-app.xs),Incl))+app.vs;

                    hold(app.UIAxes1,"on");
                    plot(app.UIAxes1,k,y,'b');
                    plot(app.UIAxes1,k,app.F,'r');
                    hold(app.UIAxes1,"off");
                    stem(app.UIAxes2,app.Wn,app.An);
                    stem(app.UIAxes3,app.Wn,app.Pn);

                    app.TabGroup.SelectedTab = app.FourierSeriesTab;

                case 4
                    app.fs=app.SamplingFrequency2050HzrecommendedEditField.Value;
                    app.T=app.TimePeriodTsEditField.Value;
                    app.amp=app.AmplitudeAEditField.Value;
                    app.xs=app.TimeShiftingEditField.Value;
                    app.vs=app.VerticalShiftingEditField.Value;
                    app.N=app.NumberofFourierCoefficients515recommendedEditField.Value;

                    choicesawtooth(app);

                    Incl=1;
                    w0=(2*pi)/app.T;
                    k=-((5*app.T/2)-1/app.fs):1/app.fs:((5*app.T/2)-1/app.fs);
                    y=(app.amp*sawtooth(w0*(k-app.xs),Incl))+app.vs;

                    hold(app.UIAxes1,"on");
                    plot(app.UIAxes1,k,y,'b');
                    plot(app.UIAxes1,k,app.F,'r');
                    hold(app.UIAxes1,"off");
                    stem(app.UIAxes2,app.Wn,app.An);
                    stem(app.UIAxes3,app.Wn,app.Pn);

                    app.TabGroup.SelectedTab = app.FourierSeriesTab;

                case 5
                    app.f=str2func(['@(t)',app.FunctionftEditField.Value]);
                    app.fs=app.SamplingFrequency2050HzrecommendedEditField.Value;
                    app.T=app.TimePeriodTsEditField.Value;
                    app.a=app.AmplitudeAEditField.Value;
                    app.xs=app.TimeShiftingEditField.Value;
                    app.vs=app.VerticalShiftingEditField.Value;
                    app.N=app.NumberofFourierCoefficients515recommendedEditField.Value;

                    choicefunction(app);

                    draw_func(app);
                    stem(app.UIAxes2,app.Wn,app.An);
                    stem(app.UIAxes3,app.Wn,app.Pn);

                    app.TabGroup.SelectedTab = app.FourierSeriesTab;
            end
            app.a0EditField.Value=double(app.a0);
            app.anTextArea.Value=cellstr(string(double(app.an)));
            app.bnTextArea.Value=cellstr(string(double(app.bn)));
            app.a0EditField_2.Value=double(app.a0);
            cellAn=cellstr(string(app.An));
            cellAn{1,1}=' ';
            app.AnTextArea.Value=cellAn;
            cellPn=cellstr(string(app.Pn));
            cellPn{1,1}=' ';
            app.PnTextArea.Value=cellPn;

            app.AverageValueEditField.Value = double(app.a0);
            ms=(app.a0)^2;
            for i=1:app.N
                ms=ms+app.an(i)^2+app.bn(i)^2;
            end
            rms=sqrt(ms);
            app.RMSvalueEditField.Value = double(rms);
        end

        % Value changed function: 
        % SamplingFrequency2050HzrecommendedEditField
        function SamplingFrequency2050HzrecommendedEditFieldValueChanged(app, event)
%             value = app.SamplingFrequency2050HzrecommendedEditField.Value;
%             app.fs=value;
        end

        % Value changed function: FunctionftEditField
        function FunctionftEditFieldValueChanged(app, event)
%              value = app.FunctionftEditField.Value;
%              app.f = value;
        end

        % Value changed function: TimePeriodTsEditField
        function TimePeriodTsEditFieldValueChanged(app, event)
%             value = app.TimePeriodTsEditField.Value;
%             app.T = value;
        end

        % Value changed function: AmplitudeAEditField
        function AmplitudeAEditFieldValueChanged(app, event)
%             value = app.AmplitudeAEditField.Value;
%             if app.ChoosetheVoltageSignalTypeDropDown.Value==5
%                 app.a = value;
%             else
%                 app.amp = value;
%             end
        end

        % Value changed function: TimeShiftingEditField
        function TimeShiftingEditFieldValueChanged(app, event)
%             value = app.TimeShiftingEditField.Value;
%             app.xs = value;
        end

        % Value changed function: VerticalShiftingEditField
        function VerticalShiftingEditFieldValueChanged(app, event)
%             value = app.VerticalShiftingEditField.Value;
%             app.vs = value;
        end

        % Value changed function: 
        % NumberofFourierCoefficients515recommendedEditField
        function NumberofFourierCoefficients515recommendedEditFieldValueChanged(app, event)
%             value = app.NumberofFourierCoefficients515recommendedEditField.Value;
%             app.N=value;
        end

        % Value changed function: ChooseloadtypeDropDown
        function ChooseloadtypeDropDownValueChanged(app, event)
            value = str2num(app.ChooseloadtypeDropDown.Value);
            
            switch value
                case 1
                    app.ResistanceohmEditField.Visible='on';
                    app.ResistanceohmEditFieldLabel.Visible='on';
                    app.InductanceHenryEditField.Visible='off';
                    app.InductanceHenryEditFieldLabel.Visible='off';
                    app.CapacitanceFaradEditField.Visible='off';
                    app.CapacitanceFaradEditFieldLabel.Visible='off';
                case 2
                    app.ResistanceohmEditField.Visible='off';
                    app.ResistanceohmEditFieldLabel.Visible='off';
                    app.InductanceHenryEditField.Visible='on';
                    app.InductanceHenryEditFieldLabel.Visible='on';
                    app.CapacitanceFaradEditField.Visible='off';
                    app.CapacitanceFaradEditFieldLabel.Visible='off';
                case 3
                    app.ResistanceohmEditField.Visible='off';
                    app.ResistanceohmEditFieldLabel.Visible='off';
                    app.InductanceHenryEditField.Visible='off';
                    app.InductanceHenryEditFieldLabel.Visible='off';
                    app.CapacitanceFaradEditField.Visible='on';
                    app.CapacitanceFaradEditFieldLabel.Visible='on';
                case 4
                    app.ResistanceohmEditField.Visible='on';
                    app.ResistanceohmEditFieldLabel.Visible='on';
                    app.InductanceHenryEditField.Visible='on';
                    app.InductanceHenryEditFieldLabel.Visible='on';
                    app.CapacitanceFaradEditField.Visible='off';
                    app.CapacitanceFaradEditFieldLabel.Visible='off';
                case 5
                    app.ResistanceohmEditField.Visible='on';
                    app.ResistanceohmEditFieldLabel.Visible='on';
                    app.InductanceHenryEditField.Visible='off';
                    app.InductanceHenryEditFieldLabel.Visible='off';
                    app.CapacitanceFaradEditField.Visible='on';
                    app.CapacitanceFaradEditFieldLabel.Visible='on';
                case 6
                    app.ResistanceohmEditField.Visible='on';
                    app.ResistanceohmEditFieldLabel.Visible='on';
                    app.InductanceHenryEditField.Visible='on';
                    app.InductanceHenryEditFieldLabel.Visible='on';
                    app.CapacitanceFaradEditField.Visible='on';
                    app.CapacitanceFaradEditFieldLabel.Visible='on';
            end
        end

        % Button pushed function: CalculatePowerRelatedtermsButton
        function CalculatePowerRelatedtermsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PowerRelatedTermsTab;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            value = str2num(app.ChooseloadtypeDropDown.Value);
            app.R=app.ResistanceohmEditField.Value;
            app.L=app.InductanceHenryEditField.Value;
            app.C=app.CapacitanceFaradEditField.Value;

            switch value
                case 1
                    PowR(app);
                case 2
                    PowL(app);
                case 3
                    PowC(app);
                case 4
                    PowRL(app);
                case 5
                    PowRC(app);
                case 6
                    PowRLC(app);
            end
            if app.Q>0 && abs(app.Q)~=0
                pftype='Lagging';
                compow=sprintf('%0.8f+%0.8fi VA',app.P,app.Q);
            elseif app.Q<0 && abs(app.Q)~=0
                pftype='Leading';
                compow=sprintf('%0.8f-%0.8fi VA',app.P,abs(app.Q));
            else
                pftype='Unity';
                compow=sprintf('%0.8f VA',app.P);
            end
            app.PowerFactorTypeEditField.Value=pftype;
            app.RealAveragePowerPEditField.Value=sprintf('%0.8f W',app.P);
            app.ReactivePowerQEditField.Value=sprintf('%0.8f VAR',app.Q);
            app.ApparentPowerSEditField.Value=sprintf('%0.8f VA',app.S);
            app.ComplexPowerSEditField.Value=compow;
            app.TotalHarmonicDistortionfundamentalfrequencyTHDFEditField.Value=sprintf('%0.8f',app.THDF);
            app.TotalHarmonicDistortionrootmeansquareTHDREditField.Value=sprintf('%0.8f',app.THDR);
            app.DisplacementpowerfactorPFDEditField.Value=sprintf('%0.8f (%s)',app.PFD,pftype);
            app.DistortionpowerfactorDPFEditField.Value=sprintf('%0.8f',app.DPF);
            app.TruePowerFactorPFEditField.Value=sprintf('%0.8f',app.PF);
        end

        % Button pushed function: ClicktoproceedButton
        function ClicktoproceedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.VoltageSignalTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image5 = uiimage(app.HomeTab);
            app.Image5.ImageSource = 'wp6047880.png';
            app.FourierSeriesandPowerCalculatorLabel = uilabel(app.HomeTab);
            app.FourierSeriesandPowerCalculatorLabel.Text = 'Fourier Series and Power Calculator';
            app.TextArea = uitextarea(app.HomeTab);
            app.TextArea.Value = {'Designed By'; 'Mostaqul Islam - 1906070'; 'Arpa Roy Dastider - 1906071'};
            app.ClicktoproceedButton = uibutton(app.HomeTab, 'push');
            app.ClicktoproceedButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoproceedButtonPushed, true);
            app.ClicktoproceedButton.Text = 'Click to proceed...';
            app.VoltageSignalTab = uitab(app.TabGroup);
            app.VoltageSignalTab.Title = 'Voltage Signal';
            app.PropertiesoftheSignalPanel = uipanel(app.VoltageSignalTab);
            app.PropertiesoftheSignalPanel.Title = 'Properties of the Signal';
            app.FunctionftLabel = uilabel(app.PropertiesoftheSignalPanel);
            app.FunctionftLabel.Visible = 'off';
            app.FunctionftLabel.Text = 'Function, f(t)';
            app.FunctionftEditField = uieditfield(app.PropertiesoftheSignalPanel, 'text');
            app.FunctionftEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionftEditFieldValueChanged, true);
            app.FunctionftEditField.Visible = 'off';
            app.SamplingFrequency2050HzrecommendedEditFieldLabel = uilabel(app.PropertiesoftheSignalPanel);
            app.SamplingFrequency2050HzrecommendedEditFieldLabel.Text = 'Sampling Frequency (20-50Hz recommended) ';
            app.SamplingFrequency2050HzrecommendedEditField = uieditfield(app.PropertiesoftheSignalPanel, 'numeric');
            app.SamplingFrequency2050HzrecommendedEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequency2050HzrecommendedEditFieldValueChanged, true);
            app.TimePeriodTsEditFieldLabel = uilabel(app.PropertiesoftheSignalPanel);
            app.TimePeriodTsEditFieldLabel.Text = 'Time Period, T(s) ';
            app.TimePeriodTsEditField = uieditfield(app.PropertiesoftheSignalPanel, 'numeric');
            app.TimePeriodTsEditField.ValueChangedFcn = createCallbackFcn(app, @TimePeriodTsEditFieldValueChanged, true);
            app.DutyCycleLabel = uilabel(app.PropertiesoftheSignalPanel);
            app.DutyCycleLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.PropertiesoftheSignalPanel, 'numeric');
            app.DutyCycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditFieldValueChanged, true);
            app.AmplitudeAEditFieldLabel = uilabel(app.PropertiesoftheSignalPanel);
            app.AmplitudeAEditFieldLabel.Text = 'Amplitude, A';
            app.AmplitudeAEditField = uieditfield(app.PropertiesoftheSignalPanel, 'numeric');
            app.AmplitudeAEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeAEditFieldValueChanged, true);
            app.TimeShiftingEditFieldLabel = uilabel(app.PropertiesoftheSignalPanel);
            app.TimeShiftingEditFieldLabel.Text = 'Time Shifting';
            app.TimeShiftingEditField = uieditfield(app.PropertiesoftheSignalPanel, 'numeric');
            app.TimeShiftingEditField.ValueChangedFcn = createCallbackFcn(app, @TimeShiftingEditFieldValueChanged, true);
            app.VerticalShiftingEditFieldLabel = uilabel(app.PropertiesoftheSignalPanel);
            app.VerticalShiftingEditFieldLabel.Text = 'Vertical Shifting';
            app.VerticalShiftingEditField = uieditfield(app.PropertiesoftheSignalPanel, 'numeric');
            app.VerticalShiftingEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalShiftingEditFieldValueChanged, true);
            app.Image = uiimage(app.VoltageSignalTab);
            app.Image.ImageSource = 'sample.jpeg';
            app.FindFourierSeriesButton = uibutton(app.VoltageSignalTab, 'push');
            app.FindFourierSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @FindFourierSeriesButtonPushed, true);
            app.FindFourierSeriesButton.Text = 'Find Fourier Series';
            app.ChoosetheVoltageSignalTypeDropDownLabel = uilabel(app.VoltageSignalTab);
            app.ChoosetheVoltageSignalTypeDropDownLabel.Text = 'Choose the Voltage Signal Type';
            app.ChoosetheVoltageSignalTypeDropDown = uidropdown(app.VoltageSignalTab);
            app.ChoosetheVoltageSignalTypeDropDown.Items = {'Square Wave', 'Rectangular Pulse Train', 'Triangular Wave', 'Swatooth Wave', 'Any Random Function'};
            app.ChoosetheVoltageSignalTypeDropDown.ItemsData = [1 2 3 4 5];
            app.ChoosetheVoltageSignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosetheVoltageSignalTypeDropDownValueChanged, true);
            app.ChoosetheVoltageSignalTypeDropDown.Value = 1;
            app.NumberofFourierCoefficientsLabel = uilabel(app.VoltageSignalTab);
            app.NumberofFourierCoefficientsLabel.Text = 'Number of Fourier Coefficients (5-15 recommended)';
            app.NumberofFourierCoefficients515recommendedEditField = uieditfield(app.VoltageSignalTab, 'numeric');
            app.NumberofFourierCoefficients515recommendedEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofFourierCoefficients515recommendedEditFieldValueChanged, true);
            app.NonSinusoidalPeriodicVoltageSignalLabel = uilabel(app.VoltageSignalTab);
            app.NonSinusoidalPeriodicVoltageSignalLabel.Text = 'Non-Sinusoidal Periodic Voltage Signal';
            app.FourierSeriesTab = uitab(app.TabGroup);
            app.FourierSeriesTab.Title = 'Fourier Series';
            app.FourierSeriesLabel = uilabel(app.FourierSeriesTab);
            app.FourierSeriesLabel.Text = 'Fourier Series';
            app.Panel = uipanel(app.FourierSeriesTab);
            app.a0EditFieldLabel = uilabel(app.Panel);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.Panel, 'numeric');
            app.anTextAreaLabel = uilabel(app.Panel);
            app.anTextAreaLabel.Text = 'an';
            app.anTextArea = uitextarea(app.Panel);
            app.bnTextAreaLabel = uilabel(app.Panel);
            app.bnTextAreaLabel.Text = 'bn';
            app.bnTextArea = uitextarea(app.Panel);
            app.Image2 = uiimage(app.FourierSeriesTab);
            app.Image2.ImageSource = 'anbn.png';
            app.Panel_2 = uipanel(app.FourierSeriesTab);
            app.a0EditField_2Label = uilabel(app.Panel_2);
            app.a0EditField_2Label.Text = 'a0';
            app.a0EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.AnTextAreaLabel = uilabel(app.Panel_2);
            app.AnTextAreaLabel.Text = 'An';
            app.AnTextArea = uitextarea(app.Panel_2);
            app.PnTextAreaLabel = uilabel(app.Panel_2);
            app.PnTextAreaLabel.Text = 'Pn';
            app.PnTextArea = uitextarea(app.Panel_2);
            app.Image3 = uiimage(app.FourierSeriesTab);
            app.Image3.ImageSource = 'AnPn.png';
            app.AverageValueLabel = uilabel(app.FourierSeriesTab);
            app.AverageValueLabel.Text = 'Average Value ';
            app.AverageValueEditField = uieditfield(app.FourierSeriesTab, 'numeric');
            app.RMSvalueLabel = uilabel(app.FourierSeriesTab);
            app.RMSvalueLabel.Text = 'RMS value ';
            app.RMSvalueEditField = uieditfield(app.FourierSeriesTab, 'numeric');
            app.CalculatePowerRelatedtermsButton = uibutton(app.FourierSeriesTab, 'push');
            app.CalculatePowerRelatedtermsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePowerRelatedtermsButtonPushed, true);
            app.CalculatePowerRelatedtermsButton.Text = 'Calculate Power Related terms';
            app.Panel_4 = uipanel(app.FourierSeriesTab);
            app.UIAxes2 = uiaxes(app.Panel_4);
            title(app.UIAxes2, 'Amplitude Spectrum')
            xlabel(app.UIAxes2, 'Angular Frequency (rad/s)')
            ylabel(app.UIAxes2, 'Amplitude of Voltage Signal(V)')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_5 = uipanel(app.FourierSeriesTab);
            app.UIAxes3 = uiaxes(app.Panel_5);
            title(app.UIAxes3, 'Phase Spectrum')
            xlabel(app.UIAxes3, 'Angular Frequency (rad/s)')
            ylabel(app.UIAxes3, 'Phase of Voltage Signal(degree)')
            zlabel(app.UIAxes3, 'Z')
            app.Panel_6 = uipanel(app.FourierSeriesTab);
            app.UIAxes1 = uiaxes(app.Panel_6);
            title(app.UIAxes1, 'Fourier Series Approximation of the signal')
            xlabel(app.UIAxes1, 'Time(s)  [The red graph is fourier aprroximation]')
            ylabel(app.UIAxes1, 'Voltage Signal(V)')
            zlabel(app.UIAxes1, 'Z')
            app.PowerRelatedTermsTab = uitab(app.TabGroup);
            app.PowerRelatedTermsTab.Title = 'Power Related Terms';
            app.PowerRelatedTermsLabel = uilabel(app.PowerRelatedTermsTab);
            app.PowerRelatedTermsLabel.Text = 'Power Related Terms';
            app.ChooseloadtypeDropDownLabel = uilabel(app.PowerRelatedTermsTab);
            app.ChooseloadtypeDropDownLabel.Text = 'Choose load type';
            app.ChooseloadtypeDropDown = uidropdown(app.PowerRelatedTermsTab);
            app.ChooseloadtypeDropDown.Items = {'Pure Resistor (R)', 'Pure Inductor (I)', 'Pure Capacitor (C)', 'R,L in series', 'R,C in series', 'R,L,C in series'};
            app.ChooseloadtypeDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ChooseloadtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseloadtypeDropDownValueChanged, true);
            app.ChooseloadtypeDropDown.Value = '1';
            app.LoadValuesPanel = uipanel(app.PowerRelatedTermsTab);
            app.LoadValuesPanel.Title = 'Load Values';
            app.ResistanceohmEditFieldLabel = uilabel(app.LoadValuesPanel);
            app.ResistanceohmEditFieldLabel.Text = 'Resistance (ohm)';
            app.ResistanceohmEditField = uieditfield(app.LoadValuesPanel, 'numeric');
            app.InductanceHenryEditFieldLabel = uilabel(app.LoadValuesPanel);
            app.InductanceHenryEditFieldLabel.Visible = 'off';
            app.InductanceHenryEditFieldLabel.Text = 'Inductance (Henry';
            app.InductanceHenryEditField = uieditfield(app.LoadValuesPanel, 'numeric');
            app.InductanceHenryEditField.Visible = 'off';
            app.CapacitanceFaradEditFieldLabel = uilabel(app.LoadValuesPanel);
            app.CapacitanceFaradEditFieldLabel.Visible = 'off';
            app.CapacitanceFaradEditFieldLabel.Text = 'Capacitance (Farad)';
            app.CapacitanceFaradEditField = uieditfield(app.LoadValuesPanel, 'numeric');
            app.CapacitanceFaradEditField.Visible = 'off';
            app.Panel_3 = uipanel(app.PowerRelatedTermsTab);
            app.PowerFactorTypeEditFieldLabel = uilabel(app.Panel_3);
            app.PowerFactorTypeEditFieldLabel.Text = 'Power Factor Type';
            app.PowerFactorTypeEditField = uieditfield(app.Panel_3, 'text');
            app.RealAveragePowerPEditFieldLabel = uilabel(app.Panel_3);
            app.RealAveragePowerPEditFieldLabel.Text = 'Real/Average Power, P';
            app.RealAveragePowerPEditField = uieditfield(app.Panel_3, 'text');
            app.ReactivePowerQEditFieldLabel = uilabel(app.Panel_3);
            app.ReactivePowerQEditFieldLabel.Text = 'Reactive Power, Q';
            app.ReactivePowerQEditField = uieditfield(app.Panel_3, 'text');
            app.ApparentPowerSEditFieldLabel = uilabel(app.Panel_3);
            app.ApparentPowerSEditFieldLabel.Text = 'Apparent Power, S';
            app.ApparentPowerSEditField = uieditfield(app.Panel_3, 'text');
            app.ComplexPowerSEditFieldLabel = uilabel(app.Panel_3);
            app.ComplexPowerSEditFieldLabel.Text = 'Complex Power, S';
            app.ComplexPowerSEditField = uieditfield(app.Panel_3, 'text');
            app.TotalHarmonicDistortionfundamentalfrequencyTHDFEditFieldLabel = uilabel(app.Panel_3);
            app.TotalHarmonicDistortionfundamentalfrequencyTHDFEditFieldLabel.Text = 'Total Harmonic Distortion (fundamental frequency), THDF';
            app.TotalHarmonicDistortionfundamentalfrequencyTHDFEditField = uieditfield(app.Panel_3, 'text');
            app.TotalHarmonicDistortionrootmeansquareTHDREditFieldLabel = uilabel(app.Panel_3);
            app.TotalHarmonicDistortionrootmeansquareTHDREditFieldLabel.Text = 'Total Harmonic Distortion (root mean square), THDR';
            app.TotalHarmonicDistortionrootmeansquareTHDREditField = uieditfield(app.Panel_3, 'text');
            app.DisplacementpowerfactorPFDEditFieldLabel = uilabel(app.Panel_3);
            app.DisplacementpowerfactorPFDEditFieldLabel.Text = 'Displacement power factor, PFD';
            app.DisplacementpowerfactorPFDEditField = uieditfield(app.Panel_3, 'text');
            app.DistortionpowerfactorDPFEditFieldLabel = uilabel(app.Panel_3);
            app.DistortionpowerfactorDPFEditFieldLabel.Text = 'Distortion power factor, DPF';
            app.DistortionpowerfactorDPFEditField = uieditfield(app.Panel_3, 'text');
            app.TruePowerFactorPFEditFieldLabel = uilabel(app.Panel_3);
            app.TruePowerFactorPFEditFieldLabel.Text = 'True Power Factor, PF';
            app.TruePowerFactorPFEditField = uieditfield(app.Panel_3, 'text');
            app.CalculateButton = uibutton(app.PowerRelatedTermsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.PowerRelatedTermsTab);
            app.Label.Text = '***If the voltage signal is applied across a load, you can calculate the power related terms...';
            app.Image4 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fourier_Series_and_Power_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end